classdef Final_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TempLamp                  matlab.ui.control.Lamp
        TempLampLabel             matlab.ui.control.Label
        CoolantLamp               matlab.ui.control.Lamp
        CoolantLampLabel          matlab.ui.control.Label
        FarLamp                   matlab.ui.control.Lamp
        FarLampLabel              matlab.ui.control.Label
        MiddleLamp                matlab.ui.control.Lamp
        MiddleLampLabel           matlab.ui.control.Label
        CloseLamp                 matlab.ui.control.Lamp
        CloseLampLabel            matlab.ui.control.Label
        DistancecmEditField       matlab.ui.control.NumericEditField
        DistancecmEditFieldLabel  matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           % Set up communications
            arduino = serialport("COM5", 9600, 'Timeout', 15);
            pause(1);
      while(true)
         flush(arduino);
         pause(0.5);
         waterStr = readline(arduino);
         water = str2double(waterStr)
 
         TempStr = readline(arduino);
         Temp = str2double(TempStr)
         if water >= 100
             app.CoolantLamp.Color = 'green';
         else
             app.CoolantLamp.Color = 'red';
         end
         if Temp <= 85
             app.TempLamp.Color = 'green';
         else
             app.TempLamp.Color = 'red';
         end
         if water < 100 || Temp > 85
             app.FarLamp.Color = 'white';
             app.MiddleLamp.Color = 'white';
             app.CloseLamp.Color = 'white';
         end
           
         
         distanceStr = readline(arduino);
         distance = str2double(distanceStr)
         app.DistancecmEditField.Value = distance;
       if water >=100 && Temp <=85
             if distance > 25
                % Turn off all lamps
                app.FarLamp.Color = 'white';
                app.MiddleLamp.Color = 'white';
                app.CloseLamp.Color = 'white';
            elseif distance > 20
                % Turn on lamp 1 and turn off lamps 2 and 3
                app.FarLamp.Color = 'green';
                app.MiddleLamp.Color = 'white';
                app.CloseLamp.Color = 'white';
            elseif distance > 15
                % Turn on lamps 1 and 2 and turn off lamp 3
                app.FarLamp.Color = 'green';
                app.MiddleLamp.Color = 'yellow';
                app.CloseLamp.Color = 'white';
            else
                % Turn on all lamps
                app.FarLamp.Color = 'green';
                app.MiddleLamp.Color = 'yellow';
                app.CloseLamp.Color = 'red';
            end
       end 
      end
            delete(arduino);
            clear arduino;


        end

        % Callback function
        function EndButtonPushed(app, event)


        end

        % Value changed function: DistancecmEditField
        function DistancecmEditFieldValueChanged(app, event)
            value = app.DistancecmEditField.Value;
            app.DistancecmEditField.Value = value;
        end

        % Callback function
        function ResumeButtonPushed(app, event)
            uiresume(app.UIFigure);
        end

        % Callback function
        function WaterLevelEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DistancecmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
            app.DistancecmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @DistancecmEditFieldValueChanged, true);
            app.CloseLampLabel = uilabel(app.UIFigure);
            app.CloseLampLabel.Text = 'Close';
            app.CloseLamp = uilamp(app.UIFigure);
            app.MiddleLampLabel = uilabel(app.UIFigure);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.UIFigure);
            app.FarLampLabel = uilabel(app.UIFigure);
            app.FarLampLabel.Text = 'Far';
            app.FarLamp = uilamp(app.UIFigure);
            app.CoolantLampLabel = uilabel(app.UIFigure);
            app.CoolantLampLabel.Text = 'Coolant';
            app.CoolantLamp = uilamp(app.UIFigure);
            app.TempLampLabel = uilabel(app.UIFigure);
            app.TempLampLabel.Text = 'Temp';
            app.TempLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end