classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ResumeButton        matlab.ui.control.Button
        DutyCycleEditField  matlab.ui.control.NumericEditField
        DutyCycleLabel      matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           % Set up communications
            arduino = serialport("COM5", 9600, "Timeout", 15);
            pause(1);
            
            numLEDs = 4; % Number of LEDs
            y = zeros(numLEDs, 101);
            voltage = zeros(numLEDs, 101);
            current = zeros(numLEDs, 101);
            resistance = zeros(numLEDs, 101);
            
            photoResistor = 10000;
            
            for led = 1:numLEDs
                hold(app.UIAxes, 'on');
                hold(app.UIAxes_2, 'on');
                hold(app.UIAxes_3, 'on');
                for K = 0:100
                    flush(arduino);
                    write(arduino, 2, 'string');
                    pause(0.5);
                    a = read(arduino, 4, 'string');
                    flush(arduino);
                    y(led, K+1) = (str2double(a)/1023)*5; % calculations

                    current(led, K+1) = y(led, K+1) / photoResistor;
                    resistance(led, K+1) = (photoResistor * (5 - (y(led, K+1))) ./ current(led, K+1));
                    disp([led, K, y(led, K+1)]);
                    if (led == 1) % Plotting
                      % Red LED
                      plot(app.UIAxes, K, y(led, K+1) , 'ro', 'MarkerFaceColor', 'red')
                      plot(app.UIAxes_2, K, current(led, K+1), 'ro', 'MarkerFaceColor', 'red')
                      plot(app.UIAxes_3, K, resistance(led, K+1), 'ro', 'MarkerFaceColor', 'red')
                      drawnow limitrate;
                    end
                    if (led == 2) % Plotting
                      % Blue LED
                      plot(app.UIAxes, K, y(led, K+1) , 'bo', 'MarkerFaceColor', 'blue')
                      plot(app.UIAxes_2, K, current(led, K+1), 'bo', 'MarkerFaceColor', 'blue')
                      plot(app.UIAxes_3, K, resistance(led, K+1), 'bo', 'MarkerFaceColor', 'blue')
                      drawnow limitrate;
                    end                    
                    if (led == 3) % Plotting
                      % Green LED
                      plot(app.UIAxes, K, y(led, K+1) , 'go', 'MarkerFaceColor', 'green')
                      plot(app.UIAxes_2, K, current(led, K+1), 'go', 'MarkerFaceColor', 'green')
                      plot(app.UIAxes_3, K, resistance(led, K+1), 'go', 'MarkerFaceColor', 'green')
                      drawnow limitrate;
                    end                    
                    if (led == 4) % Plotting
                      % Yellow LED
                      plot(app.UIAxes, K, y(led, K+1) , 'yo', 'MarkerFaceColor', 'yellow')
                      plot(app.UIAxes_2, K, current(led, K+1), 'yo', 'MarkerFaceColor', 'yellow')
                      plot(app.UIAxes_3, K, resistance(led, K+1), 'yo', 'MarkerFaceColor', 'yellow')
                      drawnow limitrate;
                    end                    
                          
                    pause(0.01);                 
                    app.DutyCycleEditField.Value = K;
                end

                disp('Press any key to continue to the next LED...');
                uiwait(app.UIFigure);
                close;
            end
            hold(app.UIAxes, 'off');
            hold(app.UIAxes_2, 'off');
            hold(app.UIAxes_3, 'off');

            delete(arduino);
            clear arduino;


        end

        % Callback function
        function EndButtonPushed(app, event)


        end

        % Value changed function: DutyCycleEditField
        function DutyCycleEditFieldValueChanged(app, event)
            value = app.DutyCycleEditField.Value;
            app.DutyCycleEditField.Value = value;
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Photocell Voltage')
            xlabel(app.UIAxes, 'Duty Cycle (%)')
            ylabel(app.UIAxes, 'Voltage (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 5];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Photocell Current vs Duty cycle')
            xlabel(app.UIAxes_2, 'Duty Cycle (%)')
            ylabel(app.UIAxes_2, 'Current (A)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 100];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Resistance vs Duty cycle')
            xlabel(app.UIAxes_3, 'Duty Cycle %')
            ylabel(app.UIAxes_3, 'Resistance ')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [0 100];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DutyCycleLabel = uilabel(app.UIFigure);
            app.DutyCycleLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DutyCycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutyCycleEditFieldValueChanged, true);
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end